classdef app_punti_simili_011_multiEvento_002_noTypes_daTermo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        binEditField                   matlab.ui.control.NumericEditField
        binEditFieldLabel              matlab.ui.control.Label
        EventiresistiviEditField       matlab.ui.control.NumericEditField
        EventiresistiviEditFieldLabel  matlab.ui.control.Label
        EventitermiciEditField         matlab.ui.control.NumericEditField
        EventitermiciEditFieldLabel    matlab.ui.control.Label
        delta_tEditField               matlab.ui.control.NumericEditField
        delta_tEditFieldLabel          matlab.ui.control.Label
        Lamp_figura                    matlab.ui.control.Lamp
        SalvafiguraButton              matlab.ui.control.Button
        Panel_grafici                  matlab.ui.container.Panel
        ValoriResistenzaLabel          matlab.ui.control.Label
        NonassociatiEditField          matlab.ui.control.EditField
        NonassociatiEditFieldLabel     matlab.ui.control.Label
        AreaLabel                      matlab.ui.control.Label
        ValoritemperaturaLabel         matlab.ui.control.Label
        NumeropuntiEditField           matlab.ui.control.EditField
        NumeropuntiEditFieldLabel      matlab.ui.control.Label
        PianoZLabel                    matlab.ui.control.Label
        Slider_Zeta                    matlab.ui.control.Slider
        Plot_graf_Tutti                matlab.ui.control.Button
        plot_resistenza_neg            matlab.ui.control.UIAxes
        plot_temperatura_pos           matlab.ui.control.UIAxes
        plot_area_pos                  matlab.ui.control.UIAxes
        plot_area_neg                  matlab.ui.control.UIAxes
        plot_temperatura_neg           matlab.ui.control.UIAxes
        plot_resistenza_pos            matlab.ui.control.UIAxes
        graf_piano                     matlab.ui.control.UIAxes
        graf_Tutti                     matlab.ui.control.UIAxes
        Lamp                           matlab.ui.control.Lamp
        RecuperaDatiButton             matlab.ui.control.Button
        fileRes                        matlab.ui.control.EditField
        EventiREditFieldLabel          matlab.ui.control.Label
        fileThermo                     matlab.ui.control.EditField
        EventiTEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        file_Tmulti_name = 'Eventi_Termo.mat';
        file_Tdoppi_name = 'Eventi_Termo_principali_doppi.mat'
        file_T_name = 'Eventi_Termo_principali.mat'
        file_R_name = 'resistance_switch.mat';
        frame_start;
        frame_diff;
        multi_termo;

        Simili;

        punti_positivi;
        punti_negativi;

        Resistenza_switch;
        termo_results;
    
        bin = 20;
        delta_t = 0.20;
        Eventi_RS_pos;
        Eventi_RS_neg;

        str_num_punti;

        numero_punti;
        Rows;
        Columns;

        Zeta;
        zeta_value_str;
        Zeta_mesh;
        Zeta_punti;
        numero_punti_evento;


        histo_resistenza_pos;
        histo_resistenza_neg;

        histo_temperatura_pos;
        histo_temperatura_neg;

        histo_area_pos;
        histo_area_neg;
    end

    methods (Access = private)
        
 %PRENDI DATI
        function [Simili, Resistenza_switch, termo_results, numero_punti] = ParametriDati(app, nome_elettro)
            app.delta_tEditField.Value = app.delta_t;

            %cartella
            check = exist('ThEl_Results');
            if check ~= 7
                mkdir('ThEl_Results');
            end

            %save the folder path where save data
            path = [pwd,'\ThEl_Results\',];
            
            cd 'parameters'
            ElectrParameters_ = load("ElectricParameters.mat");
            ThermalParameters_ = load("ThermalParameters.mat");
            cd ..
        
            %parametri
            ThermalParameters = ThermalParameters_.ThermalParameters;
            app.Rows = ThermalParameters.Rows;
            app.Columns = ThermalParameters.Columns;
            app.frame_start = ThermalParameters.fr_start;
            app.frame_diff = ThermalParameters.fr_diff;

            ElectrParameters = ElectrParameters_.ElectrParameters;
            ElectMethod = ElectrParameters.method;
            column = ElectrParameters.column; % 4 = resistance
            nsigma = ElectrParameters.nsigma;

            %prendi dati termici
            termo_results = load(app.file_T_name);
            termo_results = cell2mat(struct2cell(termo_results));

            termo_results_doppi = load(app.file_Tdoppi_name);
            termo_results_doppi = cell2mat(struct2cell(termo_results_doppi));

            app.multi_termo = load(app.file_Tmulti_name);
            app.multi_termo = cell2mat(struct2cell(app.multi_termo));
            
            %prendi dati elettrici
            Resistenza_switch = load(nome_elettro);
            Resistenza_switch = cell2mat(struct2cell(Resistenza_switch));
            
            %prendo rappresentanti
            [max_peaks, min_peaks, Eventi_termo_ridotti, matriceEventi] = evento_max_temp_007_matriceEventi(termo_results_doppi, termo_results, app.multi_termo, app.frame_start, app.frame_diff);

            %associo RS
            [app.Eventi_RS_pos, app.Eventi_RS_neg, EventiRS] = cerca_evento_termo_005_noTypes_daTermo(matriceEventi, Resistenza_switch, app.delta_t);
            app.EventitermiciEditField.Value = max(termo_results(:,8));
            app.EventiresistiviEditField.Value = length(app.Eventi_RS_pos(:,1)) + length(app.Eventi_RS_neg(:,1));

            num_non_associati = abs(length(app.Eventi_RS_pos(:,1)) + length(app.Eventi_RS_neg(:,1)) - max(termo_results(:,8)));
            app.NonassociatiEditField.Value = num2str(num_non_associati);
            
            %prendo simili
            [Simili] = cerca_punti_simili005(Eventi_termo_ridotti, app.Rows, app.Columns, app.frame_start, app.frame_diff, app.Eventi_RS_pos, app.Eventi_RS_neg);
            
            
            %slider
            numero = length(fieldnames(Simili));
            numero_punti = numero - 1;
            app.Slider_Zeta.Limits = [1 numero_punti];
            ticks = zeros(1, numero_punti);
            ticks_labels = string(zeros(1, numero_punti));
            
            for i = 1 : numero_punti
                ticks(1,i) = i;
                ticks_labels(1, i) = num2str(i);
            end
            app.Slider_Zeta.MajorTicks = ticks;
            app.Slider_Zeta.MajorTickLabels = ticks_labels;

            %salvo simili
            save("Simili")

            %lampada
            app.Lamp.Color = 'green';

        end

        %GRAFICO PUNTI SIMILI
        function grafico_simili_associati_tutti(app, numero_punti, Simili, Rows, Columns)
            hold(app.graf_Tutti, "on") 
            %figura
            app.graf_Tutti.Box ="on";
            app.graf_Tutti.XLim = [0, Columns];
            app.graf_Tutti.YLim = [0, Rows];
            colormap(app.graf_Tutti,'jet');
            clim(app.graf_Tutti, [0 numero_punti])
            colorbar(app.graf_Tutti)

            [data_x, data_y] = meshgrid(1:Columns, 1:Rows);

            for j = 1 : numero_punti
                name = append('gruppo', num2str(j));
                
                negativi = zeros(0,2);
                positivi = zeros(0,2);

                for i = 1 : length(fieldnames(Simili.(name)))
                    n_evento = i;
                    name_evento = append("evento", num2str(n_evento));

                    
                    if Simili.(name).(name_evento).massimi(1,1) ~= 0
                        for k = 1 : length(Simili.(name).(name_evento).massimi(:,1))
                            x_ref = data_x(Simili.(name).(name_evento).massimi(k,1));
                            y_ref = data_y(Simili.(name).(name_evento).massimi(k,1));
                            positivi(end+1, :) = [x_ref y_ref];
                        end
                    end

                    if Simili.(name).(name_evento).minimi(1,1) ~= 0
                        for k = 1 : length(Simili.(name).(name_evento).minimi(:,1))
                            x_ref = data_x(Simili.(name).(name_evento).minimi(k,1));
                            y_ref = data_y(Simili.(name).(name_evento).minimi(k,1));
                            negativi(end+1, :) = [x_ref y_ref];
                        end
                    end
                end

                z_pos = zeros(length(positivi(:,1)), 1);
                z_pos(1:end, 1) = j;

                z_neg = zeros(length(negativi(:,1)), 1);
                z_neg(1:end, 1) = j;
        
                scatter3(app.graf_Tutti, positivi(:,1), positivi(:,2), z_pos, 70, z_pos, 'o', 'filled', 'MarkerEdgeColor', 'black')
                scatter3(app.graf_Tutti, negativi(:,1), negativi(:,2), z_neg, 70, z_neg, 'Pentagram', 'filled', 'MarkerEdgeColor', 'black')

            end
            view(app.graf_Tutti, [-5 -2 5]);
            axis equal;
        end

        %%PIANO ZETA
        function zeta_plane(app, zeta_value, Rows, Columns, Simili)
            
            z_grid = zeros(Rows, Columns);
            z_grid(:,:) = zeta_value;
            app.Zeta_mesh = mesh(app.graf_Tutti , z_grid, 'FaceAlpha', 0.5, 'EdgeAlpha', 0.5);
            colormap(app.graf_Tutti,'jet');

            str = append('z', num2str(zeta_value));
            app.zeta_value_str = text(app.graf_piano, Columns-2, Rows-4, str, 'FontSize', 18);

            app.graf_piano.XLim = [0, Columns];
            app.graf_piano.YLim = [0, Rows];
            name = append('gruppo', num2str(zeta_value));
            [data_x, data_y] = meshgrid(1:Columns, 1:Rows);

            str_punti = num2str(Simili.(name).evento1.n_punti);
            app.numero_punti_evento = text(app.graf_piano, Columns-2, Rows-5, str_punti, 'FontSize', 18);

            negativi = zeros(0,2);
            positivi = zeros(0,2);
            numero_eventi = length(fieldnames(Simili.(name)));

            for i = 1 : length(fieldnames(Simili.(name)))
                n_evento = i;
                name_evento = append("evento", num2str(n_evento));

                if Simili.(name).(name_evento).massimi(1,1) ~= 0
                    for k = 1 : length(Simili.(name).(name_evento).massimi(:,1))
                        x_ref = data_x(Simili.(name).(name_evento).massimi(k,1));
                        y_ref = data_y(Simili.(name).(name_evento).massimi(k,1));
                        positivi(end+1, :) = [x_ref y_ref];
                    end
                end

                if Simili.(name).(name_evento).minimi(1,1) ~= 0
                    for k = 1 : length(Simili.(name).(name_evento).minimi(:,1))
                        x_ref = data_x(Simili.(name).(name_evento).minimi(k,1));
                        y_ref = data_y(Simili.(name).(name_evento).minimi(k,1));
                        negativi(end+1, :) = [x_ref y_ref];
                    end
                end
            end
            
    
            app.punti_positivi = scatter(app.graf_piano, positivi(:,1), positivi(:,2), 70, 'o', 'filled', 'MarkerEdgeColor', 'black', 'MarkerFaceColor','blue', DisplayName='positivi');
            app.punti_negativi = scatter(app.graf_piano, negativi(:,1), negativi(:,2), 70,'Pentagram', 'filled', 'MarkerEdgeColor', 'black', 'MarkerFaceColor','blue', DisplayName='negativi');

            legend(app.graf_piano);

            app.str_num_punti = num2str(numero_eventi + 1);
            app.NumeropuntiEditField.Value = app.str_num_punti;

            num_non_associati = abs(length(app.Eventi_RS_pos(:,1)) + length(app.Eventi_RS_neg(:,1)) - max(app.termo_results(:,8)));
            app.NonassociatiEditField.Value = num2str(num_non_associati);
        end
        
        %VALORI DI RESISTENZA ASSOCIATI
        function istogramma_resistenza(app)
            name = append('gruppo', num2str(app.Zeta));
            str_num_nonassociati = 0;
            negativi = zeros(0,1);
            positivi = zeros(0,1);

            for i = 1 : length(fieldnames(app.Simili.(name)))
                n_evento = i;
                name_evento = append("evento", num2str(n_evento));

                if app.Simili.(name).(name_evento).num_evento ~= 0 
                    if app.Simili.(name).(name_evento).RS < 0
                        positivi(end+1, :) = app.Simili.(name).(name_evento).RS;

                    elseif app.Simili.(name).(name_evento).RS > 0
                        negativi(end+1, :) = app.Simili.(name).(name_evento).RS;

                    elseif app.Simili.(name).(name_evento).RS == 0
                        str_num_nonassociati = str_num_nonassociati + 1;
                    end                  
                end
            end
            
                      
            app.NonassociatiEditField.Value = num2str(str_num_nonassociati);
            %istogramma
            app.histo_resistenza_pos = histogram(app.plot_resistenza_pos, positivi(:, 1), app.bin ,'FaceColor', 'red');
            app.histo_resistenza_neg = histogram(app.plot_resistenza_neg, negativi(:, 1), app.bin ,'FaceColor', 'blue');
        end

        %VALORI DI temperatura
        function istogramma_temperatura(app)
            name = append('gruppo', num2str(app.Zeta));

            negativi = zeros(0,1);
            positivi = zeros(0,1);

            for i = 1 : length(fieldnames(app.Simili.(name)))
                n_evento = i;
                name_evento = append("evento", num2str(n_evento));

                if app.Simili.(name).(name_evento).num_evento ~= 0 
                    if app.Simili.(name).(name_evento).massimi(1,1) ~= 0
                        for j = 1 : length(app.Simili.(name).(name_evento).massimi(:,1))
                            positivi(end+1, :) = app.Simili.(name).(name_evento).massimi(j,2);
                        end

                    elseif app.Simili.(name).(name_evento).minimi(1,1) ~= 0
                        for j = 1 : length(app.Simili.(name).(name_evento).minimi(:,1))
                            negativi(end+1, :) = app.Simili.(name).(name_evento).minimi(j,2);
                        end

                    elseif app.Simili.(name).(name_evento).RS == 0
                        str_num_nonassociati = str_num_nonassociati + 1;
                    end                  
                end
            end

            %histo valori resistenza
            %istogramma
            app.histo_temperatura_pos = histogram(app.plot_temperatura_pos, positivi(:,1), app.bin, 'FaceColor', 'red');
            app.histo_temperatura_neg = histogram(app.plot_temperatura_neg,  negativi(:,1), app.bin, 'FaceColor', 'blue');
        end

        %VALORI DI temperatura ASSOCIATI
        function istogramma_area(app)
            name = append('gruppo', num2str(app.Zeta));

            negativi = zeros(0,1);
            positivi = zeros(0,1);

            for i = 1 : length(fieldnames(app.Simili.(name)))
                n_evento = i;
                name_evento = append("evento", num2str(n_evento));

                if app.Simili.(name).(name_evento).num_evento ~= 0 
                    if app.Simili.(name).(name_evento).massimi(1,1) ~= 0
                        positivi(end+1, :) = app.Simili.(name).(name_evento).area_max;

                    elseif app.Simili.(name).(name_evento).minimi(1,1) ~= 0
                        negativi(end+1, :) = app.Simili.(name).(name_evento).area_min;
                    end                  
                end
            end

            app.histo_area_pos = histogram(app.plot_area_pos, positivi(:,1), app.bin, 'FaceColor', 'red');
            app.histo_area_neg = histogram(app.plot_area_neg,  negativi(:,1), app.bin, 'FaceColor', 'blue');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: fileThermo
        function fileThermoValueChanged(app, event)
            app.file_T_name = app.fileThermo.Value;
        end

        % Value changed function: fileRes
        function fileResValueChanged(app, event)
            app.file_R_name = app.fileRes.Value;            
        end

        % Button pushed function: RecuperaDatiButton
        function RecuperaDatiButtonPushed(app, event)
            [app.Simili, app.Resistenza_switch, app.termo_results, app.numero_punti] = ParametriDati(app, app.file_R_name);
        end

        % Button pushed function: Plot_graf_Tutti
        function Plot_graf_TuttiButtonPushed(app, event)
            grafico_simili_associati_tutti(app, app.numero_punti, app.Simili, app.Rows, app.Columns)
        end

        % Value changed function: Slider_Zeta
        function Slider_ZetaValueChanged(app, event)
            app.Lamp_figura.Color = "red";
            
            hold(app.graf_piano, "on")
            hold(app.plot_resistenza_pos, "on")
            hold(app.plot_temperatura_pos, "on")
            hold(app.plot_area_pos, "on")

            delete(app.Zeta_mesh);
            delete(app.punti_positivi)
            delete(app.punti_negativi)
            delete(app.histo_resistenza_pos)
            delete(app.histo_resistenza_pos)
            delete(app.histo_temperatura_pos)
            delete(app.histo_temperatura_neg)
            delete(app.histo_area_pos)
            delete(app.histo_area_neg)
            delete(app.zeta_value_str)

            app.Zeta = round(app.Slider_Zeta.Value);
            zeta_plane(app, app.Zeta, app.Rows, app.Columns, app.Simili)

            istogramma_resistenza(app)

            istogramma_temperatura(app)

            istogramma_area(app)
        end

        % Button pushed function: SalvafiguraButton
        function SalvafiguraButtonPushed(app, event)
            app.Lamp_figura.Color = "red";

            cd ThEl_Results\'Punti simili'\

            nomefile_immagine = append(num2str(app.Zeta), "_puntiSimili.jpg");

            app.Lamp_figura.Color = "green";
        end

        % Value changed function: delta_tEditField
        function delta_tEditFieldValueChanged(app, event)
            app.delta_t = app.delta_tEditField.Value;
            app.Lamp.Color = "red";
            app.Lamp_figura.Color = "red";
        end

        % Value changed function: binEditField
        function binEditFieldValueChanged(app, event)
            app.bin = app.binEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EventiTEditFieldLabel = uilabel(app.UIFigure);
            app.EventiTEditFieldLabel.Text = 'EventiT';
            app.fileThermo = uieditfield(app.UIFigure, 'text');
            app.fileThermo.ValueChangedFcn = createCallbackFcn(app, @fileThermoValueChanged, true);
            app.EventiREditFieldLabel = uilabel(app.UIFigure);
            app.EventiREditFieldLabel.Text = 'Eventi R';
            app.fileRes = uieditfield(app.UIFigure, 'text');
            app.fileRes.ValueChangedFcn = createCallbackFcn(app, @fileResValueChanged, true);
            app.RecuperaDatiButton = uibutton(app.UIFigure, 'push');
            app.RecuperaDatiButton.ButtonPushedFcn = createCallbackFcn(app, @RecuperaDatiButtonPushed, true);
            app.RecuperaDatiButton.Text = 'Recupera Dati';
            app.Lamp = uilamp(app.UIFigure);
            app.Panel_grafici = uipanel(app.UIFigure);
            app.graf_Tutti = uiaxes(app.Panel_grafici);
            title(app.graf_Tutti, 'Punti Simili e Associati')
            xlabel(app.graf_Tutti, '[n° pixel]')
            ylabel(app.graf_Tutti, '[n° pixel]')
            zlabel(app.graf_Tutti, 'Z')
            app.graf_piano = uiaxes(app.Panel_grafici);
            title(app.graf_piano, 'Piano in analisi')
            xlabel(app.graf_piano, '[n° pixel]')
            ylabel(app.graf_piano, '[n° pixel]')
            zlabel(app.graf_piano, 'Z')
            app.plot_resistenza_pos = uiaxes(app.Panel_grafici);
            title(app.plot_resistenza_pos, 'Eventi positvi')
            xlabel(app.plot_resistenza_pos, '\Deltaconductance')
            ylabel(app.plot_resistenza_pos, 'frequenza')
            zlabel(app.plot_resistenza_pos, 'Z')
            app.plot_temperatura_neg = uiaxes(app.Panel_grafici);
            title(app.plot_temperatura_neg, 'Eventi negativi')
            xlabel(app.plot_temperatura_neg, 'deltaT [K]')
            ylabel(app.plot_temperatura_neg, 'frequenza')
            zlabel(app.plot_temperatura_neg, 'Z')
            app.plot_area_neg = uiaxes(app.Panel_grafici);
            title(app.plot_area_neg, 'Eventi negativi')
            xlabel(app.plot_area_neg, 'Area [pixels]')
            ylabel(app.plot_area_neg, 'frequenza')
            zlabel(app.plot_area_neg, 'Z')
            app.plot_area_pos = uiaxes(app.Panel_grafici);
            title(app.plot_area_pos, 'Eventi positivi')
            xlabel(app.plot_area_pos, 'Area [pixels]')
            ylabel(app.plot_area_pos, 'frequenza')
            zlabel(app.plot_area_pos, 'Z')
            app.plot_temperatura_pos = uiaxes(app.Panel_grafici);
            title(app.plot_temperatura_pos, 'Eventi positivi')
            xlabel(app.plot_temperatura_pos, 'deltaT [K]')
            ylabel(app.plot_temperatura_pos, 'frequenza')
            zlabel(app.plot_temperatura_pos, 'Z')
            app.plot_resistenza_neg = uiaxes(app.Panel_grafici);
            title(app.plot_resistenza_neg, 'Eventi negativi')
            xlabel(app.plot_resistenza_neg, '\Deltaconductance')
            ylabel(app.plot_resistenza_neg, 'frequenza')
            zlabel(app.plot_resistenza_neg, 'Z')
            app.Plot_graf_Tutti = uibutton(app.Panel_grafici, 'push');
            app.Plot_graf_Tutti.ButtonPushedFcn = createCallbackFcn(app, @Plot_graf_TuttiButtonPushed, true);
            app.Plot_graf_Tutti.Text = 'Plot';
            app.Slider_Zeta = uislider(app.Panel_grafici);
            app.Slider_Zeta.MajorTicks = [];
            app.Slider_Zeta.ValueChangedFcn = createCallbackFcn(app, @Slider_ZetaValueChanged, true);
            app.Slider_Zeta.MinorTicks = [];
            app.Slider_Zeta.Value = 1;
            app.PianoZLabel = uilabel(app.Panel_grafici);
            app.PianoZLabel.Text = 'Piano Z';
            app.NumeropuntiEditFieldLabel = uilabel(app.Panel_grafici);
            app.NumeropuntiEditFieldLabel.Text = 'Numero punti';
            app.NumeropuntiEditField = uieditfield(app.Panel_grafici, 'text');
            app.ValoritemperaturaLabel = uilabel(app.Panel_grafici);
            app.ValoritemperaturaLabel.Text = 'Valori temperatura';
            app.AreaLabel = uilabel(app.Panel_grafici);
            app.AreaLabel.Text = 'Area';
            app.NonassociatiEditFieldLabel = uilabel(app.Panel_grafici);
            app.NonassociatiEditFieldLabel.Text = 'Non associati';
            app.NonassociatiEditField = uieditfield(app.Panel_grafici, 'text');
            app.ValoriResistenzaLabel = uilabel(app.Panel_grafici);
            app.ValoriResistenzaLabel.Text = 'Valori Resistenza';
            app.SalvafiguraButton = uibutton(app.UIFigure, 'push');
            app.SalvafiguraButton.ButtonPushedFcn = createCallbackFcn(app, @SalvafiguraButtonPushed, true);
            app.SalvafiguraButton.Text = 'Salva figura';
            app.Lamp_figura = uilamp(app.UIFigure);
            app.delta_tEditFieldLabel = uilabel(app.UIFigure);
            app.delta_tEditFieldLabel.Text = 'delta_t';
            app.delta_tEditField = uieditfield(app.UIFigure, 'numeric');
            app.delta_tEditField.ValueChangedFcn = createCallbackFcn(app, @delta_tEditFieldValueChanged, true);
            app.EventitermiciEditFieldLabel = uilabel(app.UIFigure);
            app.EventitermiciEditFieldLabel.Text = 'Eventi termici';
            app.EventitermiciEditField = uieditfield(app.UIFigure, 'numeric');
            app.EventiresistiviEditFieldLabel = uilabel(app.UIFigure);
            app.EventiresistiviEditFieldLabel.Text = 'Eventi resistivi';
            app.EventiresistiviEditField = uieditfield(app.UIFigure, 'numeric');
            app.binEditFieldLabel = uilabel(app.UIFigure);
            app.binEditFieldLabel.Text = 'bin';
            app.binEditField = uieditfield(app.UIFigure, 'numeric');
            app.binEditField.ValueChangedFcn = createCallbackFcn(app, @binEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_punti_simili_011_multiEvento_002_noTypes_daTermo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end