classdef app_traccia_picchi004 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Lamp_dati          matlab.ui.control.Lamp
        PrendidatiButton   matlab.ui.control.Button
        Frames_number      matlab.ui.control.NumericEditField
        FramesSlider       matlab.ui.control.Slider
        PlotButton         matlab.ui.control.Button
        FramesSliderLabel  matlab.ui.control.Label
        graf_minimi        matlab.ui.control.UIAxes
        graf_massimi       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nome_file_frames = "mtotalT.mat";
        frames;

        nome_file_eventi = "Eventi_Termo_principali.mat"
        eventi;
        massimi;
        minimi;

        Rows;
        Columns;
        n_frames;
        immagine_massimi;
        immagine_minimi;

        frame_value;
    end
    
    methods (Access = private)
        
        %PRENDI DATI
        function Funz_prendiDati(app)

            %colore lampadina
            app.Lamp_dati.Color = 'red';

            %prendi dati
            cd 'termoFiles_mat'
            app.frames = load(app.nome_file_frames);
            app.frames = cell2mat(struct2cell(app.frames));
            cd ..
            
            app.eventi = load(app.nome_file_eventi);
            app.eventi = cell2mat(struct2cell(app.eventi));
            [app.massimi, app.minimi] = evento_max_temp_003(app.eventi);

            %righe e colonne
            [app.Rows, app.Columns] = size(app.frames(:,:,1));

            %numero frames
            app.n_frames = length(app.frames(1,1,:));

            %slider
            app.FramesSlider.Limits = [1 200];
            ticks = zeros(1, 0);
            ticks_labels = string(zeros(1, 0));
            
            for i = 1 : 10 : 200
                ticks(1,end+1) = i-1;
                ticks_labels(1, end+1) = num2str(i-1);
            end
            app.FramesSlider.MajorTicks = ticks;
            app.FramesSlider.MajorTickLabels = ticks_labels;

            %lampadina
            app.Lamp_dati.Color = 'green';
        end

        %PLOT PICCHI 
        function Funz_plot_picchi(app, max_peaks, min_peaks, Rows, Columns)
            %elimino gli zeri da max_peaks e min_peaks
            i = 1;
            zeros_columns = zeros(length(max_peaks(:,1)), 1);
            max_peaks_clean = [max_peaks zeros_columns zeros_columns];

            zeros_columns = zeros(length(min_peaks(:,1)), 1);
            min_peaks_clean = [min_peaks zeros_columns zeros_columns];
        
            while i <= length(max_peaks_clean(:,1))
                if max_peaks_clean(i,1) ==0 & max_peaks_clean(i,2)==0
                    max_peaks_clean(i,:) = [];
                    i = i-1;

                else
                    c = ceil(max_peaks_clean(i,1)/Rows); %%floor up
                    r = max_peaks_clean(i,1) - Rows*(c-1);

                    max_peaks_clean(i,5) = c;
                    max_peaks_clean(i,6) = r;
                end
                i = i+1;
            end
            
            i = 1;
            while i <= length(min_peaks_clean(:,1))
                if min_peaks_clean(i,1) == 0 & min_peaks_clean(i,2) == 0
                    min_peaks_clean(i,:) = [];
                    i = i-1;

                else
                    c = ceil(min_peaks_clean(i,1)/Rows); %%floor up
                    r = min_peaks_clean(i,1) - Rows*(c-1);

                    min_peaks_clean(i,5) = c;
                    min_peaks_clean(i,6) = r;
                end
                i = i+1;
            end
            

            %grafico massimi
            if isempty(max_peaks_clean) == 0
                numPoints = length(max_peaks_clean(:,1));

                x = max_peaks_clean(:, 5);
                y = max_peaks_clean(:, 6);

                scatter(app.graf_massimi, x, y, 70,'filled', 'MarkerEdgeColor', 'black', 'MarkerFaceColor', 'white')

            end

            axis equal
            app.graf_massimi.XLim = [0, Columns];
            app.graf_massimi.YLim = [0, Rows];

            %grafico minimi
            if isempty(min_peaks_clean) == 0
                numPoints = length(min_peaks_clean(:,1));

                x = min_peaks_clean(:, 5);
                y = min_peaks_clean(:, 6);

                scatter(app.graf_massimi, x,y, 50,'filled', 'MarkerEdgeColor', 'black', 'MarkerFaceColor', 'white')
            end

            axis equal
            app.graf_minimi.XLim = [0, Columns];
            app.graf_minimi.YLim = [0, Rows];        
        end


        function Funz_plot_frame(app)
            immagine = app.frames(:,:, app.frame_value);
            app.immagine_massimi = imagesc(app.graf_massimi, immagine);
            app.immagine_minimi = imagesc(app.graf_minimi, immagine);
            
            %mappa colori
            load('ThermoColorMap1.mat');
            immagine_zeri = immagine;
            immagine_zeri = delete_zeros(immagine);
            [zmin, zmax] = bounds(immagine_zeri, "all");


            %set grafici
            clim(app.graf_massimi, [20 30]);
            colormap(app.graf_massimi, cm);
            colorbar(app.graf_massimi);

            clim(app.graf_minimi, [15 zmax]);
            colormap(app.graf_minimi, cm);
            colorbar(app.graf_minimi);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PrendidatiButton
        function PrendidatiButtonPushed(app, event)
            Funz_prendiDati(app);
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            app.frame_value = round(event.Value);
            app.Frames_number.Value = app.frame_value;

            delete(app.immagine_massimi);
            delete(app.immagine_minimi);

            Funz_plot_frame(app);
            Funz_plot_picchi(app, app.massimi, app.minimi, app.Rows, app.Columns)
        end

        % Value changed function: Frames_number
        function Frames_numberValueChanged(app, event)
            app.frame_value = app.Frames_number.Value;
            app.FramesSlider.Value = app.frame_value;

            delete(app.immagine_massimi);
            delete(app.immagine_minimi);

            Funz_plot_frame(app);
            Funz_plot_picchi(app, app.massimi, app.minimi, app.Rows, app.Columns)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            hold(app.graf_minimi, "on")
            hold(app.graf_massimi, "on")
            
            Funz_plot_picchi(app, app.massimi, app.minimi, app.Rows, app.Columns)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graf_massimi = uiaxes(app.UIFigure);
            title(app.graf_massimi, 'Massimi')
            xlabel(app.graf_massimi, '[n° pixel]')
            ylabel(app.graf_massimi, '[n° pixel]')
            zlabel(app.graf_massimi, 'Z')
            app.graf_massimi.XLim = [0 1];
            app.graf_massimi.YLim = [0 1];
            app.graf_massimi.ZLim = [0 1];
            app.graf_minimi = uiaxes(app.UIFigure);
            title(app.graf_minimi, 'Minimi')
            xlabel(app.graf_minimi, '[n° pixel]')
            ylabel(app.graf_minimi, '[n° pixel]')
            zlabel(app.graf_minimi, 'Z')
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.Frames_number = uieditfield(app.UIFigure, 'numeric');
            app.Frames_number.ValueChangedFcn = createCallbackFcn(app, @Frames_numberValueChanged, true);
            app.PrendidatiButton = uibutton(app.UIFigure, 'push');
            app.PrendidatiButton.ButtonPushedFcn = createCallbackFcn(app, @PrendidatiButtonPushed, true);
            app.PrendidatiButton.Text = 'Prendi dati';
            app.Lamp_dati = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_traccia_picchi004

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end